classdef FEMLabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMLabUIFigure              matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SavesettingsMenu            matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        MesherTab                   matlab.ui.container.Tab
        CurrentMeshPanel            matlab.ui.container.Panel
        MeshreadyLampLabel          matlab.ui.control.Label
        MeshreadyLamp               matlab.ui.control.Lamp
        TableSetRegions             matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
        DefaultprojPanel            matlab.ui.container.Panel
        ElementtypeButtonGroup      matlab.ui.container.ButtonGroup
        Triangle1stOrderButton      matlab.ui.control.RadioButton
        Triangle2ndOrderButton      matlab.ui.control.RadioButton
        Triangle3rdOrderButton      matlab.ui.control.RadioButton
        ElemTypeAxes                matlab.ui.control.UIAxes
        RunsetupButton              matlab.ui.control.Button
        MeshrefinementPanel         matlab.ui.container.Panel
        FactorSpinnerLabel          matlab.ui.control.Label
        FactorSpinner               matlab.ui.control.Spinner
        RegionsPanel                matlab.ui.container.Panel
        TableEditRegions            matlab.ui.control.Table
        EditmeshButton              matlab.ui.control.Button
        UpdateparamsButton          matlab.ui.control.Button
        PreprocessorTab             matlab.ui.container.Tab
        ProjectSetupPanel           matlab.ui.container.Panel
        ProblemtypeButtonGroup      matlab.ui.container.ButtonGroup
        ElectrostaticButton         matlab.ui.control.RadioButton
        StaticcurrentButton         matlab.ui.control.RadioButton
        MagnetostaticButton         matlab.ui.control.RadioButton
        SymmetryButtonGroup         matlab.ui.container.ButtonGroup
        PlanesymmetricButton        matlab.ui.control.RadioButton
        AxissymmetricButton         matlab.ui.control.RadioButton
        CalculationtypeButtonGroup  matlab.ui.container.ButtonGroup
        IntegrationButton           matlab.ui.control.RadioButton
        ExactformulaButton          matlab.ui.control.RadioButton
        CleanprojectButton          matlab.ui.control.Button
        ProcessButton               matlab.ui.control.Button
        StatePanel                  matlab.ui.container.Panel
        PreprocessingLampLabel      matlab.ui.control.Label
        PreprocessingLamp           matlab.ui.control.Lamp
        RegionparametersPanel       matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        EdgetypeButtonGroup         matlab.ui.container.ButtonGroup
        RectangularButton           matlab.ui.control.RadioButton
        CircularButton              matlab.ui.control.RadioButton
        PostprocessorTab            matlab.ui.container.Tab
    end

    
    properties (Access = public)
        projPath = pwd; % Path to project
        projName = 'Default.proj'; % Initial project name
        
        settingsFilePth = [pwd, '\settings.mat'];
        plotsPth = [pwd, '\plots\'];
        resultsPth = [pwd, '\results\'];
    end
    
    properties (Access = private)
        
       projectSelected = 1;
       
       files;
       
       settings = struct('problemClass', 'Electrostatic',... 
                         'symmetry', 'Plane symmetric',...
                         'computation', 'Integration', ...
                         'edgeType', 'Rectangular');
       
    end
    
    methods (Access = private)
        
        % Changes the visibility of the tools if no proj selected
        function changeProjectState(app, select)
            app.projectSelected = select;
            if(~app.projectSelected)
                app.TabGroup.Visible = 'off';
            else
                app.TabGroup.Visible = 'on';
            end
        end
        
        function state = checkButtonState(app, button)
            value = button.Text;
            switch value
                case 'Electrostatic'
                    state = app.STATIC_E;
                case 'Static current'
                    state = app.STATIC_C;
                case 'Magnetostatic'
                    state = app.STATIC_M;
                case 'Plane symmetric'
                    state = app.PLANE_SYM;
                case 'Axis symmetric'
                    state = app.AXIS_SYM;
                case 'Integration'
                    state = app.COMP_INT;
                case 'Exact formula'
                    state = app.COMP_FORM;
                case 'Rectangular'
                    state = app.RECT;
                case 'Circular'
                    state = app.CIRC;
            end
        end
       
        function updateProblemSettings(app)
            
            settings = app.settings;
            save(app.files.setfile, 'settings')
            
        end
    
        function changePreprocState(app, state)
            if(strcmp(state, 'busy'))
                app.PreprocessingLamp.Color = [1,1,0];    
            elseif(strcmp(state, 'ready'))
                app.PreprocessingLamp.Color = [0,1,0];
            elseif(strcmp(state, 'init'))
                app.PreprocessingLamp.Color = [1,0,0];
            end
        end
    
        function changeMesherState(app, state)
            if(strcmp(state, 'busy'))
                app.MeshreadyLamp.Color = [1,1,0];    
            elseif(strcmp(state, 'ready'))
                app.MeshreadyLamp.Color = [0,1,0];
            elseif(strcmp(state, 'init'))
                app.MeshreadyLamp.Color = [1,0,0];
            end
        end
    
        function startPreprocessor(app)
            
            app.changePreprocState('busy')
            msh.prepFieldData(app.files, app.settings)
            slv.calcA(app.files, app.settings)
            app.changePreprocState('ready')
            
        end
    end
    
    methods (Access = public)
        
        function initComponents(app)
            
            addpath('functions')
            
            % Project selected state : 
            app.changeProjectState(0)
            
            % ElemTypeAxes init :
            title(app.ElemTypeAxes, '')
            ylabel(app.ElemTypeAxes, '')
            xlabel(app.ElemTypeAxes, '')
            app.setElementTypeImg(1)
            
            % Project name init
            app.setProjectTitle()
            
            %% Lamps intializer :
            
            % MeshreadyLamp
            app.MeshreadyLamp.Color = [1,0,0];
            
            % Preprocessed lamp
            app.PreprocessingLamp.Color = [1,0,0];
            
        end
        
        % Sets the element type image
        function setElementTypeImg(app, i)
            img = imread(['./AppFiles/figures/', num2str(i), 'o_triangle.png']);
            imshow(img, 'parent', app.ElemTypeAxes)
        end
        
        % Sets project title 
        function setProjectTitle(app)
            app.ProjectSetupPanel.Title = app.projName;
            app.DefaultprojPanel.Title = app.projName;
        end
        
        
    end
    

    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.projPath = uigetdir(pwd, 'Select project folder');
            pathParts = strsplit(app.projPath, '\');
            app.projName = pathParts{end};
            app.changeProjectState(1)
            app.files = io.parseGmesh(app.projPath, app.projName);
            app.setProjectTitle()
            
            
        end

        % Selection changed function: ProblemtypeButtonGroup
        function ProblemtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemtypeButtonGroup.SelectedObject;
            app.settings.problemClass = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Selection changed function: SymmetryButtonGroup
        function SymmetryButtonGroupSelectionChanged(app, event)
            selectedButton = app.SymmetryButtonGroup.SelectedObject;
            app.settings.symmetry = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Selection changed function: CalculationtypeButtonGroup
        function CalculationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationtypeButtonGroup.SelectedObject;
            app.settings.computation = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Selection changed function: EdgetypeButtonGroup
        function EdgetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.EdgetypeButtonGroup.SelectedObject;
            app.settings.edgeType = selectedButton.Text;
            app.updateProblemSettings();
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.startPreprocessor()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMLabUIFigure = uifigure;
            app.FEMLabUIFigure.Name = 'FEMLab';
            app.FileMenu = uimenu(app.FEMLabUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SavesettingsMenu = uimenu(app.FileMenu);
            app.SavesettingsMenu.Accelerator = 'S';
            app.SavesettingsMenu.Text = 'Save settings';
            app.HelpMenu = uimenu(app.FEMLabUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.FEMLabUIFigure);
            app.MesherTab = uitab(app.TabGroup);
            app.MesherTab.Title = 'Mesher';
            app.CurrentMeshPanel = uipanel(app.MesherTab);
            app.CurrentMeshPanel.Title = 'Current Mesh ';
            app.MeshreadyLampLabel = uilabel(app.CurrentMeshPanel);
            app.MeshreadyLampLabel.Text = 'Mesh ready';
            app.MeshreadyLamp = uilamp(app.CurrentMeshPanel);
            app.TableSetRegions = uitable(app.CurrentMeshPanel);
            app.TableSetRegions.ColumnName = {'# Nodes'; '# Elements'; 'Element type'};
            app.TableSetRegions.RowName = {};
            app.UIAxes = uiaxes(app.CurrentMeshPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DefaultprojPanel = uipanel(app.MesherTab);
            app.DefaultprojPanel.Title = 'Default.proj';
            app.ElementtypeButtonGroup = uibuttongroup(app.DefaultprojPanel);
            app.ElementtypeButtonGroup.Title = 'Element type :';
            app.Triangle1stOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle1stOrderButton.Text = 'Triangle 1st Order';
            app.Triangle1stOrderButton.Value = true;
            app.Triangle2ndOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle2ndOrderButton.Text = 'Triangle 2nd Order';
            app.Triangle3rdOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle3rdOrderButton.Text = 'Triangle 3rd Order';
            app.ElemTypeAxes = uiaxes(app.ElementtypeButtonGroup);
            title(app.ElemTypeAxes, 'Title')
            xlabel(app.ElemTypeAxes, 'X')
            ylabel(app.ElemTypeAxes, 'Y')
            app.ElemTypeAxes.XTick = [];
            app.ElemTypeAxes.YTick = [];
            app.RunsetupButton = uibutton(app.DefaultprojPanel, 'push');
            app.RunsetupButton.Text = 'Run setup';
            app.MeshrefinementPanel = uipanel(app.DefaultprojPanel);
            app.MeshrefinementPanel.Title = 'Mesh refinement : ';
            app.FactorSpinnerLabel = uilabel(app.MeshrefinementPanel);
            app.FactorSpinnerLabel.Text = 'Factor : ';
            app.FactorSpinner = uispinner(app.MeshrefinementPanel);
            app.FactorSpinner.Value = 1;
            app.RegionsPanel = uipanel(app.DefaultprojPanel);
            app.RegionsPanel.Title = 'Regions : ';
            app.TableEditRegions = uitable(app.RegionsPanel);
            app.TableEditRegions.ColumnName = {'ID'; 'Name'; 'Type'};
            app.TableEditRegions.RowName = {};
            app.TableEditRegions.ColumnEditable = [true false false];
            app.EditmeshButton = uibutton(app.DefaultprojPanel, 'push');
            app.EditmeshButton.Text = 'Edit mesh';
            app.UpdateparamsButton = uibutton(app.DefaultprojPanel, 'push');
            app.UpdateparamsButton.Text = 'Update params';
            app.PreprocessorTab = uitab(app.TabGroup);
            app.PreprocessorTab.Title = 'Preprocessor';
            app.ProjectSetupPanel = uipanel(app.PreprocessorTab);
            app.ProjectSetupPanel.Title = 'Default.proj';
            app.ProblemtypeButtonGroup = uibuttongroup(app.ProjectSetupPanel);
            app.ProblemtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemtypeButtonGroupSelectionChanged, true);
            app.ProblemtypeButtonGroup.Title = 'Problem type : ';
            app.ElectrostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.ElectrostaticButton.Text = 'Electrostatic';
            app.ElectrostaticButton.Value = true;
            app.StaticcurrentButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.StaticcurrentButton.Text = 'Static current';
            app.MagnetostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.MagnetostaticButton.Text = 'Magnetostatic';
            app.SymmetryButtonGroup = uibuttongroup(app.ProjectSetupPanel);
            app.SymmetryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SymmetryButtonGroupSelectionChanged, true);
            app.SymmetryButtonGroup.Title = 'Symmetry :';
            app.PlanesymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.PlanesymmetricButton.Text = 'Plane symmetric';
            app.PlanesymmetricButton.Value = true;
            app.AxissymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.AxissymmetricButton.Text = 'Axis symmetric';
            app.CalculationtypeButtonGroup = uibuttongroup(app.ProjectSetupPanel);
            app.CalculationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationtypeButtonGroupSelectionChanged, true);
            app.CalculationtypeButtonGroup.Title = 'Calculation type : ';
            app.IntegrationButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.IntegrationButton.Text = 'Integration';
            app.IntegrationButton.Value = true;
            app.ExactformulaButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.ExactformulaButton.Text = 'Exact formula';
            app.CleanprojectButton = uibutton(app.ProjectSetupPanel, 'push');
            app.CleanprojectButton.Text = 'Clean project';
            app.ProcessButton = uibutton(app.ProjectSetupPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.StatePanel = uipanel(app.ProjectSetupPanel);
            app.StatePanel.Title = 'State : ';
            app.PreprocessingLampLabel = uilabel(app.StatePanel);
            app.PreprocessingLampLabel.Text = 'Preprocessing';
            app.PreprocessingLamp = uilamp(app.StatePanel);
            app.RegionparametersPanel = uipanel(app.ProjectSetupPanel);
            app.RegionparametersPanel.Title = 'Region parameters : ';
            app.UITable = uitable(app.RegionparametersPanel);
            app.UITable.ColumnName = {'Name'; 'Parameter'};
            app.UITable.RowName = {};
            app.EdgetypeButtonGroup = uibuttongroup(app.ProjectSetupPanel);
            app.EdgetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EdgetypeButtonGroupSelectionChanged, true);
            app.EdgetypeButtonGroup.Title = 'Edge type : ';
            app.RectangularButton = uiradiobutton(app.EdgetypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.CircularButton = uiradiobutton(app.EdgetypeButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.PostprocessorTab = uitab(app.TabGroup);
            app.PostprocessorTab.Title = 'Postprocessor';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FEMLabApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMLabUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMLabUIFigure)
        end
    end
end